classdef SignalAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TimeWindowsSpinner          matlab.ui.control.Spinner
        TimeWindowsSpinnerLabel     matlab.ui.control.Label
        SavefilterdCheckBox         matlab.ui.control.CheckBox
        SaveRawCheckBox             matlab.ui.control.CheckBox
        ai0Lowpass                  matlab.ui.control.NumericEditField
        ai0Highpass                 matlab.ui.control.NumericEditField
        ai1Lowpass                  matlab.ui.control.NumericEditField
        ai1Highpass                 matlab.ui.control.NumericEditField
        HighpassHzLabel_2           matlab.ui.control.Label
        LowpassHzEditFieldLabel_2   matlab.ui.control.Label
        HighpassHzLabel             matlab.ui.control.Label
        LowpassHzEditFieldLabel     matlab.ui.control.Label
        ConnectStateLabel           matlab.ui.control.Label
        ConnectNiDaqButton          matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        SampleRateHzEditField       matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel  matlab.ui.control.Label
        ai0SignalPlot               matlab.ui.control.UIAxes
        ai1SignalPlot               matlab.ui.control.UIAxes
    end

    properties (Access = public)
        CallingApp; % Main app
        NiDaq;
        Filter;
        DataBuffer;
        Marker;
        Timer;
    end
    
    methods (Access = private)
        function bandp = decideFilter0(app)
            lowpassFc = app.ai0Lowpass.Value;
            highpassFc = app.ai0Highpass.Value;
            fs = app.SampleRateHzEditField.Value;
            
            if lowpassFc > 0 && highpassFc > 0
                Wn_low = 2*lowpassFc/fs;
                Wn_high = 2*highpassFc/fs;
                bandp = fir1(8,[Wn_high,Wn_low],"bandpass");
            else
                uialert(app.UIFigure,'Values of Lowpass& Highpass must be more than zero, less than 1/2 sample rate!','WARNING!');
                return;
            end
        end

        function bandp = decideFilter1(app)
            lowpassFc = app.ai1Lowpass.Value;
            highpassFc = app.ai1Highpass.Value;
            fs = app.SampleRateHzEditField.Value;
            
            if lowpassFc > 0 && highpassFc > 0
                Wn_low = 2*lowpassFc/fs;
                Wn_high = 2*highpassFc/fs;
                bandp = fir1(8,[Wn_high,Wn_low],"bandpass");
            else
                uialert(app.UIFigure,'Values of Lowpass& Highpass must be more than zero, less than 1/2 sample rate','WARNING!');
                return;
            end
        end
                
    end
    
    methods (Access = public)
        
        function startMarker(app)
            %% 脑电同步1：在数组末尾写入1
            t = toc(app.Timer);
            fs = app.SampleRateHzEditField.Value;
            newLength = fix(t * fs);
            
            % 如果Marker不存在或为空，则初始化
            if ~isfield(app, 'Marker') || isempty(app.Marker)
                app.Marker = zeros(newLength, 1);
            elseif length(app.Marker) < newLength
                % 如果数组需要扩展，只扩展所需部分
                app.Marker(end+1:newLength) = 0;
            end
            % 在末尾添加标记
            app.Marker(newLength) = 1;
        end

        function stopMarker(app)
            %% 脑电同步2：原理同1
            t = toc(app.Timer);
            fs = app.SampleRateHzEditField.Value;
            newLength = fix(t * fs);
            app.Marker(end+1:newLength) = 0;
            % 在末尾添加标记
            app.Marker(newLength) = 2;
        end

        function buffer_height = readSignal(app)
            data = read(app.NiDaq,"all");
            app.DataBuffer.Raw = [app.DataBuffer.Raw; data];
            buffer_height = height(app.DataBuffer.Raw);
        end

        function plotSignal(app,~,~)
            data = read(app.NiDaq,"all");
            app.DataBuffer.Raw = [app.DataBuffer.Raw; data];
            buffer_height = height(app.DataBuffer.Raw);
            fs = app.NiDaq.Rate;
            tw = app.TimeWindowsSpinner.Value;
            f0 = app.Filter.f0;
            f1 = app.Filter.f1;

            current = (buffer_height-1)/fs;

            if current-tw <= 0
                x = linspace(0,current,buffer_height);
                y_0 = app.DataBuffer.Raw.Dev1_ai0(1:buffer_height);
                y_1 = app.DataBuffer.Raw.Dev1_ai1(1:buffer_height);
            else
                x = linspace(current-tw,current,tw*fs);
                twvector = buffer_height-tw*fs+1:buffer_height;
                y_0 = app.DataBuffer.Raw.Dev1_ai0(twvector);
                y_1 = app.DataBuffer.Raw.Dev1_ai1(twvector);
            end

            downsample_factor = 4;
            x = downsample(x, downsample_factor);
            y_0 = downsample(y_0, downsample_factor);
            y_1 = downsample(y_1, downsample_factor);

            y_0_filt = filtfilt(f0,1,y_0);
            y_1_filt = filtfilt(f1,1,y_1);

            %%
            plot(app.ai0SignalPlot,x,y_0_filt);
            text(app.ai0SignalPlot,"Units","normalized","Position",[1,1],"String",sprintf('%0.1f',current));

            plot(app.ai1SignalPlot,x,y_1_filt);
            text(app.ai1SignalPlot,"Units","normalized","Position",[1,1],"String",sprintf('%0.1f',current));

            if current-tw <= 0
                app.ai0SignalPlot.XLim = [0,tw];
                app.ai1SignalPlot.XLim = [0,tw];
            else
                app.ai0SignalPlot.XLim = [current-tw,current];
                app.ai1SignalPlot.XLim = [current-tw,current];
            end
            drawnow limitrate nocallbacks;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = caller;    % Main App

            % DataBuffer initialization
            app.DataBuffer.Raw = [];
            app.Marker = [];
        end

        % Close request function: UIFigure
        function SignalAcquisitionCloseRequest(app, event)
            % Enable the Plot Options button in main app, if the app is
            if isvalid(app.CallingApp)
                app.CallingApp.CallSignalAcquisition.Enable = 'on';
            end
            % Delete the dialog box 
            delete(app);
        end

        % Button pushed function: ConnectNiDaqButton
        function ConnectNiDaqButtonPushed(app, event)
            try
                progress = uiprogressdlg(app.UIFigure,'Title','Connecting',...
                    'Indeterminate','on');

                app.NiDaq = daq("ni");
                app.NiDaq.Rate = app.SampleRateHzEditField.Value;

                % in0, in1 -> EEG, EMG; in2-> marker
                addinput(app.NiDaq,"Dev1","ai0","Voltage");
                addinput(app.NiDaq,"Dev1","ai1","Voltage");
                % addinput(app.NiDaq,"Dev1","ai2","Voltage");

                % scansAvailable settings for real-time plotting
                fs = app.SampleRateHzEditField.Value;
                app.NiDaq.ScansAvailableFcn = @(src,evt) app.plotSignal(src,evt);
                %% 默认每1秒进行一次采样和绘图
                app.NiDaq.ScansAvailableFcnCount = fs;

                % required fcn for signal analyzes
                % app.NiDaq.ScansRequiredFcn = @(src,evt) app.analyseSignal(src,evt);
                % app.NiDaq.ScansRequiredFcnCount = 10*fs;

                % set start button to work
                app.StartButton.Enable = "on";
                app.ConnectNiDaqButton.Enable = "off";

                % change the color and text content of state label
                app.ConnectStateLabel.Text = "Ni Daq Connected";
                app.ConnectStateLabel.FontColor = [0,0,0];
                app.SampleRateHzEditField.Editable = "on";
                close(progress);

            catch ME
                close(progress);
                % warning
                uialert(app.UIFigure,ME.message ...
                    ,'Warning','Icon','warning');
                return;
            end
        end

        % Value changed function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanged(app, event)
            value = app.SampleRateHzEditField.Value;
            app.NiDaq.ScansAvailableFcnCount = 0.1*value;
            % Change NiDaq Sampling Rate
            app.NiDaq.Rate = value;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Filter.f0 = decideFilter0(app);
            app.Filter.f1 = decideFilter1(app);

            start(app.NiDaq,"continuous");a
            app.Timer = tic;

            % change states of Buttons and EditFields
            % app.DetectDropDown.Enable = "off";
            app.SampleRateHzEditField.Editable = "off";
            app.ai0Highpass.Editable = "off";
            app.ai1Highpass.Editable = "off";
            app.ai0Lowpass.Editable = "off";
            app.ai1Lowpass.Editable = "off";
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "on";
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.NiDaq);

            % retrieve data available
            if app.NiDaq.NumScansAvailable > 0
                last_raw = read(app.NiDaq,"all");
                app.DataBuffer.Raw = [app.DataBuffer.Raw; last_raw];
                app.Marker(end+1:height(app.DataBuffer.Raw))=0;
            end

            % 准备基本文件路径和索引
            pth = app.CallingApp.FilePathEditField.Value;
            fileIndex = app.CallingApp.VideoFileIndexSpinner.Value;
            filePrefix = sprintf("_%05d.mat", fileIndex);


            % 使用一次对话框选择文件夹，而不是每次都弹出
            dirPath = uigetdir(pth, '选择保存数据的文件夹');
            if dirPath == 0  % 用户取消选择
                dirPath = pth;  % 使用默认路径
            end
            
            % 保存原始数据
            if app.SaveRawCheckBox.Value
                raw = app.DataBuffer.Raw;
                rawFilename = fullfile(dirPath, ['Raw', filePrefix]);
                save(rawFilename, 'raw');
                disp(['已保存原始数据: ', rawFilename]);
            end
            
            % 保存滤波数据
            if app.SavefilterdCheckBox.Value
                % 滤波处理
                y_0 = app.DataBuffer.Raw.Dev1_ai0;
                y_1 = app.DataBuffer.Raw.Dev1_ai1;
                y_0_filt = filtfilt(app.Filter.f0, 1, y_0);
                y_1_filt = filtfilt(app.Filter.f1, 1, y_1);
                data_filt = [y_0_filt, y_1_filt];
                
                filtFilename = fullfile(dirPath, ['Filt', filePrefix]);
                save(filtFilename, 'data_filt');
                disp(['已保存滤波数据: ', filtFilename]);
            end
            
            % 保存标记数据
            marker = app.Marker';
            markerFilename = fullfile(dirPath, ['Marker', filePrefix]);
            save(markerFilename, 'marker');
            disp(['已保存标记数据: ', markerFilename]);
            
            % 显示保存成功消息
            msg = sprintf('所有数据已保存至:\n%s', dirPath);
            uialert(app.UIFigure, msg, '保存成功', 'Icon', 'success');

            app.DataBuffer.Raw = [];
            app.Marker = [];
            % change states of Buttons and EditFields
            app.SampleRateHzEditField.Editable = "on";
            app.ai0Highpass.Editable = "on";
            app.ai1Highpass.Editable = "on";
            app.ai0Lowpass.Editable = "on";
            app.ai1Lowpass.Editable = "on";
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "off";  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @SignalAcquisitionCloseRequest, true);
            app.ai1SignalPlot = uiaxes(app.UIFigure);
            title(app.ai1SignalPlot, 'IN 1')
            xlabel(app.ai1SignalPlot, 'Time(s)')
            ylabel(app.ai1SignalPlot, 'Amp.(V)')
            zlabel(app.ai1SignalPlot, 'Z')
            app.ai1SignalPlot.XLim = [0 5];
            app.ai1SignalPlot.YLim = [-0.05 0.05];
            app.ai1SignalPlot.XTickLabel = '';
            app.ai0SignalPlot = uiaxes(app.UIFigure);
            title(app.ai0SignalPlot, 'IN 0')
            xlabel(app.ai0SignalPlot, 'Time(s)')
            ylabel(app.ai0SignalPlot, 'Amp.(V)')
            zlabel(app.ai0SignalPlot, 'Z')
            app.ai0SignalPlot.XLim = [0 5];
            app.ai0SignalPlot.YLim = [-0.05 0.05];
            app.ai0SignalPlot.XTickLabel = '';
            app.SampleRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleRateHzEditField.Limits = [1000 100000];
            app.SampleRateHzEditField.ValueDisplayFormat = '%.0f';
            app.SampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanged, true);
            app.SampleRateHzEditField.Editable = 'off';
            app.SampleRateHzEditField.Value = 1000;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.ConnectNiDaqButton = uibutton(app.UIFigure, 'push');
            app.ConnectNiDaqButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectNiDaqButtonPushed, true);
            app.ConnectNiDaqButton.Text = 'Connect Ni Daq';
            app.ConnectStateLabel = uilabel(app.UIFigure);
            app.ConnectStateLabel.Text = 'Waiting for connecting...';
            app.LowpassHzEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassHzEditFieldLabel.Text = 'Lowpass (Hz)';
            app.HighpassHzLabel = uilabel(app.UIFigure);
            app.HighpassHzLabel.Text = 'Highpass (Hz)';
            app.LowpassHzEditFieldLabel_2 = uilabel(app.UIFigure);
            app.LowpassHzEditFieldLabel_2.Text = 'Lowpass (Hz)';
            app.HighpassHzLabel_2 = uilabel(app.UIFigure);
            app.HighpassHzLabel_2.Text = 'Highpass (Hz)';
            app.ai1Highpass = uieditfield(app.UIFigure, 'numeric');
            app.ai1Highpass.Limits = [0 Inf];
            app.ai1Highpass.Value = 100;
            app.ai1Lowpass = uieditfield(app.UIFigure, 'numeric');
            app.ai1Lowpass.Limits = [0 Inf];
            app.ai1Lowpass.Value = 450;
            app.ai0Highpass = uieditfield(app.UIFigure, 'numeric');
            app.ai0Highpass.Limits = [0 Inf];
            app.ai0Highpass.Value = 0.5;
            app.ai0Lowpass = uieditfield(app.UIFigure, 'numeric');
            app.ai0Lowpass.Limits = [0 Inf];
            app.ai0Lowpass.Value = 40;
            app.SaveRawCheckBox = uicheckbox(app.UIFigure);
            app.SaveRawCheckBox.Text = 'Save Raw';
            app.SaveRawCheckBox.Value = true;
            app.SavefilterdCheckBox = uicheckbox(app.UIFigure);
            app.SavefilterdCheckBox.Text = 'Save filterd';
            app.SavefilterdCheckBox.Value = true;
            app.TimeWindowsSpinnerLabel = uilabel(app.UIFigure);
            app.TimeWindowsSpinnerLabel.Text = 'Time Window (s)';
            app.TimeWindowsSpinner = uispinner(app.UIFigure);
            app.TimeWindowsSpinner.Limits = [2 20];
            app.TimeWindowsSpinner.RoundFractionalValues = 'on';
            app.TimeWindowsSpinner.ValueDisplayFormat = '%.0f';
            app.TimeWindowsSpinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalAcquisition(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end